classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        equationSolution             matlab.ui.container.Tab
        KhongphnlyEditFieldLabel     matlab.ui.control.Label
        startField                   matlab.ui.control.NumericEditField
        endField                     matlab.ui.control.NumericEditField
        SaisEditFieldLabel           matlab.ui.control.Label
        errorField                   matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel      matlab.ui.control.Label
        result                       matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel          matlab.ui.control.Label
        iteration                    matlab.ui.control.NumericEditField
        summit                       matlab.ui.control.Button
        NhpphngtrnhEditFieldLabel    matlab.ui.control.Label
        equationField                matlab.ui.control.EditField
        ChnphngphpDropDownLabel      matlab.ui.control.Label
        ChonPhuongPhap               matlab.ui.control.DropDown
        LuTextAreaLabel              matlab.ui.control.Label
        LuTextArea                   matlab.ui.control.TextArea
        UIAxes                       matlab.ui.control.UIAxes
        NisuyTab                     matlab.ui.container.Tab
        ChnphngphpnisuyButtonGroup   matlab.ui.container.ButtonGroup
        ChiaiButton_2                matlab.ui.control.RadioButton
        LpButton_2                   matlab.ui.control.RadioButton
        NewtonButton_2               matlab.ui.control.RadioButton
        DycungButton_2               matlab.ui.control.RadioButton
        TnhButton_2                  matlab.ui.control.Button
        NhpdliuxEditFieldLabel       matlab.ui.control.Label
        NhpdliuxEditField            matlab.ui.control.EditField
        SaisEditField_2Label         matlab.ui.control.Label
        SaisEditField_2              matlab.ui.control.NumericEditField
        KtqunghimEditField_2Label    matlab.ui.control.Label
        KtqunghimEditField_2         matlab.ui.control.NumericEditField
        SlnlpEditField_2Label        matlab.ui.control.Label
        SlnlpEditField_2             matlab.ui.control.NumericEditField
        NhpdliuyEditFieldLabel       matlab.ui.control.Label
        NhpdliuyEditField            matlab.ui.control.EditField
        UIAxes_2                     matlab.ui.control.UIAxes
        HiquyTab                     matlab.ui.container.Tab
        hoiQuyMethodButtonGroup      matlab.ui.container.ButtonGroup
        regressionOptionButton       matlab.ui.control.RadioButton
        exponentialOptionButton      matlab.ui.control.RadioButton
        logaritOptionButton          matlab.ui.control.RadioButton
        NhpdliuxEditField_3Label_2   matlab.ui.control.Label
        hoiQuyDuLieuYField           matlab.ui.control.EditField
        NhpdliuyEditField_3Label_2   matlab.ui.control.Label
        hoiQuyDuLieuXField           matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel   matlab.ui.control.Label
        hoiQuyPredictorEditField     matlab.ui.control.NumericEditField
        hoiQuyPredictionButton       matlab.ui.control.Button
        hoiQuyPredictionResultTitle  matlab.ui.control.Label
        hoiQuyPredictionResultLabel  matlab.ui.control.Label
        hoiQuyDrawerButton           matlab.ui.control.Button
        phuongTrinhHoiQuyTitle       matlab.ui.control.Label
        phuongTrinhHoiQuyLabel       matlab.ui.control.Label
        hoiQuyErrorMessage           matlab.ui.control.Label
        hoiquyUIAxes                 matlab.ui.control.UIAxes
        ohmTab                       matlab.ui.container.Tab
        TchphnTab                    matlab.ui.container.Tab
        NhpdliuxEditField_3Label     matlab.ui.control.Label
        dulieuXField                 matlab.ui.control.EditField
        NhpdliuyEditField_3Label     matlab.ui.control.Label
        dulieuYField                 matlab.ui.control.EditField
        HocLabel                     matlab.ui.control.Label
        NhphmsEditFieldLabel         matlab.ui.control.Label
        hamTichPhanField             matlab.ui.control.EditField
        tichPhanOptionsButtonGroup   matlab.ui.container.ButtonGroup
        HnhthangButton               matlab.ui.control.RadioButton
        Simpson13Button              matlab.ui.control.RadioButton
        Simpson38Button              matlab.ui.control.RadioButton
        NhpNEditFieldLabel           matlab.ui.control.Label
        nField                       matlab.ui.control.NumericEditField
        KtquEditFieldLabel           matlab.ui.control.Label
        resultTichPhanField          matlab.ui.control.NumericEditField
        solveTichPhanButton          matlab.ui.control.Button
        NhpcntnhTchphnLabel          matlab.ui.control.Label
        aEditFieldLabel              matlab.ui.control.Label
        canTichPhanAField            matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        canTichPhanBField            matlab.ui.control.NumericEditField
        tichphanErrorLabel           matlab.ui.control.Label
        GiithiunhmTab                matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function equationFieldValueChanging(app, event)
            
        end

        % Button pushed function: summit
        function summitButtonPushed(app, event)
            Option = app.ChonPhuongPhap.Value;
            f = app.equationField.Value;
            a = app.startField.Value;
            b = app.endField.Value;
            saiso = app.errorField.Value;
            if (Option == "Chia đôi")
                [nghiem, solanlap] = chiadoi(f, a, b, saiso);
                app.result.Value = nghiem;
                app.iteration.Value = solanlap; 
            elseif (Option == "Lặp")
                fb = f;
                [nghiem, solanlap] = lap(fb,a,b,saiso)
                app.result.Value = nghiem;
                app.iteration.Value = solanlap; 
            elseif (Option == "Tiếp tuyến")
                [nghiem, solanlap] = tieptuyen(f,a,b,saiso)
                app.result.Value = nghiem;
                app.iteration.Value = solanlap; 
            end    
            syms x;
            f = str2sym(f);
            f = symfun(f,x);
            fplot(app.UIAxes,f);
        end

        % Callback function
        function startFieldValueChanged(app, event)
            
        end

        % Button pushed function: solveTichPhanButton
        function solveTichPhanButtonPushed(app, event)
            try
                x = app.dulieuXField.Value;
                y = app.dulieuYField.Value;

                if (app.hamTichPhanField.Value) 
                    fx = app.hamTichPhanField.Value;
                    fx = str2func(['@(x)' fx]);
                else
                    x = str2num(x);
                    y = str2num(y);
                    fx = Lagrange(x, y);
                end
                
                a = app.canTichPhanAField.Value;
                b = app.canTichPhanBField.Value;
                
                n = app.nField.Value;
    
                option = app.tichPhanOptionsButtonGroup.SelectedObject.Value;
    
                kq = 0;
                disp(option);
                if (option == app.HnhthangButton.Value) 
                    kq = tichphanhinhthang(fx, a, b, n);  
                elseif (option == app.Simpson13Button.Value)
                    kq = tichphanSimpson13(fx, a, b, n);
                elseif (option == app.Simpson38Button.Value)
                    kq = tichphanSimpson38(fx, a, b, n);
                end
                  
                app.resultTichPhanField.Value = kq;
                app.tichphanErrorLabel.Text = '';
            catch exception
                app.tichphanErrorLabel.Text = exception.message;
                disp(exception);
            end
            
        end

        % Button pushed function: hoiQuyDrawerButton
        function hoiQuyDrawerButtonPushed(app, event)
            try
                a = app.hoiQuyDuLieuXField.Value;
                a = str2num(a);
                b = app.hoiQuyDuLieuYField.Value;
                b = str2num(b);
                disp(a, b);
                
                option = app.hoiQuyMethodButtonGroup.SelectedObject.Value;
                fx = 0;
                if (option == app.regressionOptionButton.Value)
                    fx = linearRegression(a, b);
                elseif (option == app.exponentialOptionButton.Value)
                    fx = exponentialRegression(a, b);
                elseif (option == app.logaritOptionButton.Value) 
                    fx = logarithmicRegression(a, b);
                end

                n = min(a) : 0.1 : max(a);
                plot(app.hoiquyUIAxes, n, fx(n), 'b', 'LineWidth', 1);
    %             scatter(app.hoiquyUIAxes, a, b, 'r', 'LineWidth', 1);
    
                s = func2str(fx);
                app.phuongTrinhHoiQuyLabel.Text = s(5:end);
                app.hoiQuyErrorMessage.Text = '';
            catch e
                app.hoiQuyErrorMessage.Text = e.message;
                disp(e);
            end
        end

        % Button pushed function: hoiQuyPredictionButton
        function hoiQuyPredictionButtonPushed(app, event)
            try
                a = app.hoiQuyDuLieuXField.Value;
                a = str2num(a);
                b = app.hoiQuyDuLieuYField.Value;
                b = str2num(b);
                
                option = app.hoiQuyMethodButtonGroup.SelectedObject.Value;
                fx = 0;
                if (option == app.regressionOptionButton.Value)
                    fx = linearRegression(a, b);
                elseif (option == app.exponentialOptionButton.Value)
                    fx = exponentialRegression(a, b);
                elseif (option == app.logaritOptionButton.Value) 
                    fx = logarithmicRegression(a, b);
                end
                
                x = app.hoiQuyPredictorEditField.Value;
                app.hoiQuyPredictionResultLabel.Text = num2str(fx(x));
                app.hoiQuyErrorMessage.Text = '';
            catch e
                app.hoiQuyErrorMessage.Text = e.message;
                disp(e);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.equationSolution = uitab(app.TabGroup);
            app.equationSolution.Title = 'Nghiệm';
            app.KhongphnlyEditFieldLabel = uilabel(app.equationSolution);
            app.KhongphnlyEditFieldLabel.Text = 'Khoảng phân ly';
            app.startField = uieditfield(app.equationSolution, 'numeric');
            app.endField = uieditfield(app.equationSolution, 'numeric');
            app.SaisEditFieldLabel = uilabel(app.equationSolution);
            app.SaisEditFieldLabel.Text = 'Sai số';
            app.errorField = uieditfield(app.equationSolution, 'numeric');
            app.KtqunghimEditFieldLabel = uilabel(app.equationSolution);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.result = uieditfield(app.equationSolution, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.equationSolution);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.iteration = uieditfield(app.equationSolution, 'numeric');
            app.summit = uibutton(app.equationSolution, 'push');
            app.summit.ButtonPushedFcn = createCallbackFcn(app, @summitButtonPushed, true);
            app.summit.Text = 'Tính';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.equationSolution);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.equationField = uieditfield(app.equationSolution, 'text');
            app.ChnphngphpDropDownLabel = uilabel(app.equationSolution);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.ChonPhuongPhap = uidropdown(app.equationSolution);
            app.ChonPhuongPhap.Items = {'Chia đôi', 'Lặp', 'Tiếp tuyến', ''};
            app.ChonPhuongPhap.Value = 'Chia đôi';
            app.LuTextAreaLabel = uilabel(app.equationSolution);
            app.LuTextAreaLabel.Text = 'Lưu ý!!!';
            app.LuTextArea = uitextarea(app.equationSolution);
            app.LuTextArea.Editable = 'off';
            app.LuTextArea.Value = {'Khi sử dụng hàm lặp phải đổi phương trình sang hàm lặp'};
            app.UIAxes = uiaxes(app.equationSolution);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.ChnphngphpnisuyButtonGroup = uibuttongroup(app.NisuyTab);
            app.ChnphngphpnisuyButtonGroup.Title = 'Chọn phương pháp nội suy';
            app.ChiaiButton_2 = uiradiobutton(app.ChnphngphpnisuyButtonGroup);
            app.ChiaiButton_2.Text = 'Chia đôi';
            app.ChiaiButton_2.Value = true;
            app.LpButton_2 = uiradiobutton(app.ChnphngphpnisuyButtonGroup);
            app.LpButton_2.Text = 'Lặp';
            app.NewtonButton_2 = uiradiobutton(app.ChnphngphpnisuyButtonGroup);
            app.NewtonButton_2.Text = 'Newton';
            app.DycungButton_2 = uiradiobutton(app.ChnphngphpnisuyButtonGroup);
            app.DycungButton_2.Text = 'Dây cung';
            app.TnhButton_2 = uibutton(app.NisuyTab, 'push');
            app.TnhButton_2.Text = 'Tính';
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField = uieditfield(app.NisuyTab, 'text');
            app.SaisEditField_2Label = uilabel(app.NisuyTab);
            app.SaisEditField_2Label.Text = 'Sai số';
            app.SaisEditField_2 = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunghimEditField_2Label = uilabel(app.NisuyTab);
            app.KtqunghimEditField_2Label.Text = 'Kết quả nghiệm';
            app.KtqunghimEditField_2 = uieditfield(app.NisuyTab, 'numeric');
            app.SlnlpEditField_2Label = uilabel(app.NisuyTab);
            app.SlnlpEditField_2Label.Text = 'Số lần lặp';
            app.SlnlpEditField_2 = uieditfield(app.NisuyTab, 'numeric');
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.NisuyTab, 'text');
            app.UIAxes_2 = uiaxes(app.NisuyTab);
            title(app.UIAxes_2, 'Đồ thị hàm số')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.hoiQuyMethodButtonGroup = uibuttongroup(app.HiquyTab);
            app.hoiQuyMethodButtonGroup.Title = 'Chọn phương pháp';
            app.regressionOptionButton = uiradiobutton(app.hoiQuyMethodButtonGroup);
            app.regressionOptionButton.Text = 'Tuyến tính';
            app.regressionOptionButton.Value = true;
            app.exponentialOptionButton = uiradiobutton(app.hoiQuyMethodButtonGroup);
            app.exponentialOptionButton.Text = 'Hàm mũ';
            app.logaritOptionButton = uiradiobutton(app.hoiQuyMethodButtonGroup);
            app.logaritOptionButton.Text = 'Logarit';
            app.NhpdliuxEditField_3Label_2 = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_3Label_2.Text = 'Nhập dữ liệu x';
            app.hoiQuyDuLieuYField = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuyEditField_3Label_2 = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_3Label_2.Text = 'Nhập dữ liệu y';
            app.hoiQuyDuLieuXField = uieditfield(app.HiquyTab, 'text');
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.hoiQuyPredictorEditField = uieditfield(app.HiquyTab, 'numeric');
            app.hoiQuyPredictionButton = uibutton(app.HiquyTab, 'push');
            app.hoiQuyPredictionButton.ButtonPushedFcn = createCallbackFcn(app, @hoiQuyPredictionButtonPushed, true);
            app.hoiQuyPredictionButton.Text = 'Tính';
            app.hoiQuyPredictionResultTitle = uilabel(app.HiquyTab);
            app.hoiQuyPredictionResultTitle.Text = 'Kết quả dự đoán: y = ';
            app.hoiQuyPredictionResultLabel = uilabel(app.HiquyTab);
            app.hoiQuyPredictionResultLabel.Text = '';
            app.hoiQuyDrawerButton = uibutton(app.HiquyTab, 'push');
            app.hoiQuyDrawerButton.ButtonPushedFcn = createCallbackFcn(app, @hoiQuyDrawerButtonPushed, true);
            app.hoiQuyDrawerButton.Text = 'Tìm phương trình';
            app.phuongTrinhHoiQuyTitle = uilabel(app.HiquyTab);
            app.phuongTrinhHoiQuyTitle.Text = 'Kết quả phương trình hồi quy';
            app.phuongTrinhHoiQuyLabel = uilabel(app.HiquyTab);
            app.phuongTrinhHoiQuyLabel.Text = '';
            app.hoiQuyErrorMessage = uilabel(app.HiquyTab);
            app.hoiQuyErrorMessage.Text = '';
            app.hoiquyUIAxes = uiaxes(app.HiquyTab);
            title(app.hoiquyUIAxes, 'Đồ thị')
            xlabel(app.hoiquyUIAxes, 'X')
            ylabel(app.hoiquyUIAxes, 'Y')
            zlabel(app.hoiquyUIAxes, 'Z')
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.NhpdliuxEditField_3Label = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x';
            app.dulieuXField = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuyEditField_3Label = uilabel(app.TchphnTab);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y';
            app.dulieuYField = uieditfield(app.TchphnTab, 'text');
            app.HocLabel = uilabel(app.TchphnTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.TchphnTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.hamTichPhanField = uieditfield(app.TchphnTab, 'text');
            app.tichPhanOptionsButtonGroup = uibuttongroup(app.TchphnTab);
            app.tichPhanOptionsButtonGroup.Title = 'Chon phương pháp tính Tích phân';
            app.HnhthangButton = uiradiobutton(app.tichPhanOptionsButtonGroup);
            app.HnhthangButton.Text = 'Hình thang';
            app.HnhthangButton.Value = true;
            app.Simpson13Button = uiradiobutton(app.tichPhanOptionsButtonGroup);
            app.Simpson13Button.Text = 'Simpson 1/3';
            app.Simpson38Button = uiradiobutton(app.tichPhanOptionsButtonGroup);
            app.Simpson38Button.Text = 'Simpson 3/8';
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.nField = uieditfield(app.TchphnTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.TchphnTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.resultTichPhanField = uieditfield(app.TchphnTab, 'numeric');
            app.solveTichPhanButton = uibutton(app.TchphnTab, 'push');
            app.solveTichPhanButton.ButtonPushedFcn = createCallbackFcn(app, @solveTichPhanButtonPushed, true);
            app.solveTichPhanButton.Text = 'Giải';
            app.NhpcntnhTchphnLabel = uilabel(app.TchphnTab);
            app.NhpcntnhTchphnLabel.Text = 'Nhập cận để tính Tích phân';
            app.aEditFieldLabel = uilabel(app.TchphnTab);
            app.aEditFieldLabel.Text = 'a';
            app.canTichPhanAField = uieditfield(app.TchphnTab, 'numeric');
            app.bEditFieldLabel = uilabel(app.TchphnTab);
            app.bEditFieldLabel.Text = 'b';
            app.canTichPhanBField = uieditfield(app.TchphnTab, 'numeric');
            app.tichphanErrorLabel = uilabel(app.TchphnTab);
            app.tichphanErrorLabel.Text = '';
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.Image = uiimage(app.GiithiunhmTab);
            app.Image.ImageSource = 'tabgioithieu.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end